classdef Blade < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesignerUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        H                      matlab.ui.control.NumericEditField
        HeightofTowerLabel     matlab.ui.control.Label
        Lamda                  matlab.ui.control.NumericEditField
        TipspeedratioLabel     matlab.ui.control.Label
        minus                  matlab.ui.control.Image
        plus                   matlab.ui.control.Image
        AirfoilLabel           matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        BladeDesignerLabel     matlab.ui.control.Label
        Profils                matlab.ui.control.Table
        R                      matlab.ui.control.NumericEditField
        LngedBlattmLabel       matlab.ui.control.Label
        ParameterLabel         matlab.ui.control.Label
        z                      matlab.ui.control.NumericEditField
        BlattanzahlLabel       matlab.ui.control.Label
        t                      matlab.ui.control.NumericEditField
        BlattachseLabel        matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        Image2                 matlab.ui.control.Image
        PreviewLabel           matlab.ui.control.Label
        modellieren            matlab.ui.control.Image
        DModellingLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        addProfil  % Input
        Mod        % 3D Model
    end
    
    properties (Access = public)
        % Airfoil  % List

        drmin
        drmax      %    dr
        m          %    Profil
        i          %  - Profil
    end
    
    methods (Access = public)
        
        % Input to List
        function Proflist = neuProfil(app,Profil,dr)
            neu = {dr Profil};
            app.Profils.Data = [app.Profils.Data; neu];
            
            % writetable(app.Profils.Data,"Airfoil.xlsx",'Sheet',1); 
            
        end

        % 3D Model and Performance
        function result = Model3D(app,Solid,Perf)
            if Solid*Perf == 0
                msgbox('BUG!!! did not work!');
            end
           
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: plus
        function add(app, event)
%           app.add.Enable = 'off';               + Profil
            
            if app.R.Value == 0
                msgbox('Please give the radius of the rotor at first :D', ...
                   'Radius','help');
            else
                app.addProfil = BladeIn(app,app.drmin,app.drmax);
            end

            app.m = size(app.Profils.Data);
            app.m = app.m(1,1);

        end

        % Cell edit callback: Profils
        function ProfilsCellEdit(app, event)
           % indices = event.Indices;          List to Preview
           % newData = event.NewData;
           
           if (app.R.Value == 0) || (app.H.Value == 0) || ...
              (app.z.Value == 0) || (app.Lamda.Value == 0) || ...
              (app.m == 0)
              % nothing
           else
              % xfoil调用function更新Vorschau  ...
              foil_import(app.Profil.Data)

           end

        end

        % Cell selection callback: Profils
        function ProfilsCellSelection(app, event)
            indices = event.Indices;             % - Profil
            app.i = indices(1,1);

        end

        % Image clicked function: minus
        function delet(app, event)
            % m = size(app.Profils.Data);        % - Profil
            % m = m(1,1);

%             if app.i == 1
%                 app.Profils.Data = app.Profils.Data(2:app.m,:);
%             else 
%                 if app.i == app.m
%                     app.Profils.Data = app.Profils.Data(1:app.m-1,:);
%                 else
%                     app.Profils.Data = ...
%                       [app.Profils.Data(1:app.i-1,:) ; ...
%                        app.Profils.Data(app.i+1:app.m,:)];
%                 end
%             end

            app.Profils.Data(app.i,:) = [];

            app.m = size(app.Profils.Data);
            app.m = app.m(1,1);
            
        end

        % Image clicked function: modellieren
        function Model(app, event)
           %                                  Model and Performance

           if (app.R.Value == 0) || (app.H.Value == 0) || ...
              (app.z.Value == 0) || (app.Lamda.Value == 0) || ...
              (app.m == 0)
              msgbox('Please give the parameters and the airfoils at first :D', ...
                   '3D Model with Performance','help');
           else
              app.Mod = BladeOut(app,app.H,app.R,app.t,app.z,app.Lamda, ...
                                app.Profils.Data);
           end

        end

        % Close request function: BladeDesignerUIFigure
        function Close(app, event)
            delete(app.addProfil)
            delete(app)
        end

        % Value changed function: R
        function Radius(app, event)
            % app.R = app.R.Value;       
            app.drmin = 0.1 * app.R.Value;
            app.drmax = app.R.Value;

            if isempty(app.Profils.Data) == 0    % Profil dr
                str = "Please check if all the airfoils located from "+app.drmin+"m to "+app.drmax+"m.";
                msgbox(str,'Location of airfoils','help');
            end

            if app.H.Value < 1.2*app.R.Value     % Height Safe
                app.H.Value = 0;
                str = "The height of the tower should be higher than "+1.2*app.R.Value+ ...
                "m to make the rotor with a radius of "+app.R.Value+"m safe.";
                msgbox(str,'Height of the Tower','help');
            end
            
        end

        % Value changed function: H
        function Height(app, event)
            % app.H = app.H.Value;               Height Safe

            if app.H.Value < 1.2*app.R.Value
                app.H.Value = 0;
                str = "The height of the tower should be higher than "+1.2*app.R.Value+ ...
                "m to make the rotor with a radius of "+app.R.Value+"m safe.";
                msgbox(str,'Height of the Tower','help');
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BladeDesignerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {485, 485};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {275, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesignerUIFigure = uifigure('Visible', 'off');
            app.BladeDesignerUIFigure.Name = 'Blade Designer';
            app.BladeDesignerUIFigure.CloseRequestFcn = createCallbackFcn(app, @Close, true);
            app.BladeDesignerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BladeDesignerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BlattachseLabel = uilabel(app.LeftPanel);
            app.BlattachseLabel.Text = 'Axis of Blade [%]';
            app.t = uieditfield(app.LeftPanel, 'numeric');
            app.t.Limits = [0 100];
            app.t.ValueDisplayFormat = '%.0f';
            app.BlattanzahlLabel = uilabel(app.LeftPanel);
            app.BlattanzahlLabel.Text = 'Number of Blade';
            app.z = uieditfield(app.LeftPanel, 'numeric');
            app.z.Limits = [0 Inf];
            app.z.ValueDisplayFormat = '%.0f';
            app.ParameterLabel = uilabel(app.LeftPanel);
            app.ParameterLabel.Text = 'Parameter';
            app.LngedBlattmLabel = uilabel(app.LeftPanel);
            app.LngedBlattmLabel.Text = 'Radius of Rotor [m]';
            app.R = uieditfield(app.LeftPanel, 'numeric');
            app.R.Limits = [0 Inf];
            app.R.ValueDisplayFormat = '%.2f';
            app.R.ValueChangedFcn = createCallbackFcn(app, @Radius, true);
            app.Profils = uitable(app.LeftPanel);
            app.Profils.ColumnName = {'Radius'; 'Airfoil'};
            app.Profils.RowName = {};
            app.Profils.ColumnSortable = [true false];
            app.Profils.SelectionType = 'row';
            app.Profils.CellEditCallback = createCallbackFcn(app, @ProfilsCellEdit, true);
            app.Profils.CellSelectionCallback = createCallbackFcn(app, @ProfilsCellSelection, true);
            app.BladeDesignerLabel = uilabel(app.LeftPanel);
            app.BladeDesignerLabel.Text = 'Blade Designer';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Blatt.png';
            app.AirfoilLabel = uilabel(app.LeftPanel);
            app.AirfoilLabel.Text = 'Airfoil';
            app.plus = uiimage(app.LeftPanel);
            app.plus.ImageClickedFcn = createCallbackFcn(app, @add, true);
            app.plus.ImageSource = 'plus.png';
            app.minus = uiimage(app.LeftPanel);
            app.minus.ImageClickedFcn = createCallbackFcn(app, @delet, true);
            app.minus.ImageSource = 'minus.png';
            app.TipspeedratioLabel = uilabel(app.LeftPanel);
            app.TipspeedratioLabel.Text = 'Tip-speed ratio';
            app.Lamda = uieditfield(app.LeftPanel, 'numeric');
            app.Lamda.Limits = [0 Inf];
            app.HeightofTowerLabel = uilabel(app.LeftPanel);
            app.HeightofTowerLabel.Text = 'Height of Tower [m]';
            app.H = uieditfield(app.LeftPanel, 'numeric');
            app.H.Limits = [0 Inf];
            app.H.ValueDisplayFormat = '%.2f';
            app.H.ValueChangedFcn = createCallbackFcn(app, @Height, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.DModellingLabel = uilabel(app.RightPanel);
            app.DModellingLabel.Text = '3D Modelling';
            app.modellieren = uiimage(app.RightPanel);
            app.modellieren.ImageClickedFcn = createCallbackFcn(app, @Model, true);
            app.modellieren.ImageSource = 'model.png';
            app.PreviewLabel = uilabel(app.RightPanel);
            app.PreviewLabel.Text = 'Preview';
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = 'vorschau.jpg';
            % Show the figure after all components are created
            app.BladeDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Blade

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesignerUIFigure)
        end
    end
end