classdef BladeIn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddingAirfoilUIFigure  matlab.ui.Figure
        Profil                 matlab.ui.control.DropDown
        AirfoilDropDownLabel   matlab.ui.control.Label
        PleasecompletetheinformationaboutthenewairfoilLabel  matlab.ui.control.Label
        Entfern                matlab.ui.control.Button
        mLabel                 matlab.ui.control.Label
        Hinzu                  matlab.ui.control.Button
        dr                     matlab.ui.control.NumericEditField
        PositionLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp 
    end
    
    properties (Access = public)
        min
        max   % dr
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Blade, min, max)
            app.CallingApp = Blade;
            app.min = min;
            app.max = max;

        end

        % Value changed function: dr
        function drValueChanged(app, event)
            
%             if (app.dr.Value > app.max) || (app.dr.Value < app.min)     
%                 app.dr.Value = 0;
%                 str = "For blade of a rotor with a radius of "+app.max+ ...
%                     "m, the airfoil can located from "+app.min+"m to "+app.max+"m.";
%                 msgbox(str,'Location of airfoil','help');
%             end

            % dr vs R

        end

        % Button pushed function: Hinzu
        function HinzuPushed(app, event)
%           app.CallingApp.OptionsButton.Enable = 'on';

            if (app.dr.Value > app.max) || (app.dr.Value < app.min)     
                app.dr.Value = 0;
                str = "For blade of a rotor with a radius of "+app.max+ ...
                    "m, the airfoil can located from "+app.min+"m to "+app.max+"m.";
                msgbox(str,'Location of airfoil','help');
            else
                neuProfil(app.CallingApp,app.Profil.Value,app.dr.Value);
                delete(app)
            end

        end

        % Button pushed function: Entfern
        function EntfernButtonPushed(app, event)
%           app.CallingApp.OptionsButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddingAirfoilUIFigure = uifigure('Visible', 'off');
            app.AddingAirfoilUIFigure.Name = 'Adding Airfoil';
            app.PositionLabel = uilabel(app.AddingAirfoilUIFigure);
            app.PositionLabel.Text = 'at the Radius of';
            app.dr = uieditfield(app.AddingAirfoilUIFigure, 'numeric');
            app.dr.Limits = [0 Inf];
            app.dr.ValueDisplayFormat = '%.2f';
            app.dr.ValueChangedFcn = createCallbackFcn(app, @drValueChanged, true);
            app.Hinzu = uibutton(app.AddingAirfoilUIFigure, 'push');
            app.Hinzu.ButtonPushedFcn = createCallbackFcn(app, @HinzuPushed, true);
            app.Hinzu.Text = 'Adding';
            app.mLabel = uilabel(app.AddingAirfoilUIFigure);
            app.mLabel.Text = '[  m  ]';
            app.Entfern = uibutton(app.AddingAirfoilUIFigure, 'push');
            app.Entfern.ButtonPushedFcn = createCallbackFcn(app, @EntfernButtonPushed, true);
            app.Entfern.Text = 'Give up';
            app.PleasecompletetheinformationaboutthenewairfoilLabel = uilabel(app.AddingAirfoilUIFigure);
            app.PleasecompletetheinformationaboutthenewairfoilLabel.Text = 'Please complete the information about the new airfoil:';
            app.AirfoilDropDownLabel = uilabel(app.AddingAirfoilUIFigure);
            app.AirfoilDropDownLabel.Text = 'Airfoil';
            app.Profil = uidropdown(app.AddingAirfoilUIFigure);
            app.Profil.Items = {'NACA0012', 'NACA0014'};
            app.Profil.Value = 'NACA0012';
            % Show the figure after all components are created
            app.AddingAirfoilUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BladeIn(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AddingAirfoilUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AddingAirfoilUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddingAirfoilUIFigure)
        end
    end
end